classdef SeizureAnalysisEwellLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeizureAnalysis10UIFigure      matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        BehaviorlooksasButtonGroup     matlab.ui.container.ButtonGroup
        OtherButton                    matlab.ui.control.RadioButton
        RestingButton                  matlab.ui.control.RadioButton
        GroomingButton                 matlab.ui.control.RadioButton
        Stage5Button                   matlab.ui.control.RadioButton
        Stage4Button                   matlab.ui.control.RadioButton
        Stage3Button                   matlab.ui.control.RadioButton
        Stage2Button                   matlab.ui.control.RadioButton
        Stage1Button                   matlab.ui.control.RadioButton
        SeizureoccursonLabel           matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        LFPlooksasButtonGroup          matlab.ui.container.ButtonGroup
        ContinuationButton             matlab.ui.control.RadioButton
        ArtifactButton                 matlab.ui.control.RadioButton
        SeizureButton                  matlab.ui.control.RadioButton
        VideolagSpinner                matlab.ui.control.Spinner
        VideolagSpinnerLabel           matlab.ui.control.Label
        PlotsettingsLabel              matlab.ui.control.Label
        DetectionScoringLabel          matlab.ui.control.Label
        CommentTextArea                matlab.ui.control.TextArea
        CommentTextAreaLabel           matlab.ui.control.Label
        SamplingRatekHzEditField       matlab.ui.control.NumericEditField
        SamplingRatekHzEditFieldLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SeizureDetectionButton         matlab.ui.control.Button
        PlotLFPButton                  matlab.ui.control.Button
        TimeWindowsEditField           matlab.ui.control.NumericEditField
        TimeWindowsEditFieldLabel      matlab.ui.control.Label
        SelectChannels                 matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        VideoSwitch                    matlab.ui.control.Switch
        VideoSwitchLabel               matlab.ui.control.Label
        ReplotButton                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        ReadFilesButton                matlab.ui.control.Button
        DateDropDown                   matlab.ui.control.DropDown
        DateDropDownLabel              matlab.ui.control.Label
        MouseIDDropDown                matlab.ui.control.DropDown
        MouseIDDropDownLabel           matlab.ui.control.Label
        path                           matlab.ui.control.EditField
        ChooseWorkingDirectoryButton   matlab.ui.control.Button
        NextSeizureButton              matlab.ui.control.Button
        PreviousSeizureButton          matlab.ui.control.Button
        PlayVideoButton                matlab.ui.control.Button
        VideoPlaybackPanel             matlab.ui.container.Panel
        LFPViewerPanel                 matlab.ui.container.Panel
        TimeminSlider                  matlab.ui.control.Slider
        TimeminSliderLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % properties = variables that are available across the app in each
    % function
    properties (Access = private)
      
        UserID; % user id  - who did the scoring?
        ListMember; % integer, which file to open in the working directory (1st file = 1st hour, nth file = nth hour)
        FileList; %struct to store file name - list for ts/amp/video + configs;
        Mouse; %struct that helps organizing data: id, # of channels, port
        Data; %struct to store ts/amp/video + configs
        MouseMember; %which mouse will be analyzed (0,1,2,3)
        CurrentChannels; %which channels are used? e.g.[8 23] if Mouse #1 was selected -> depends on which mouse was selected
        ChannelLabel; %hard-coded to track hemisphere
        RecordingTime; %when the recording started (indicated in the ts file name)
        rbs; %Checkbox list so user can select from CurrentChannels (e.g. user wants to use only channel 8 for Mouse #1)
        IsSelected; %vector to track if mouse and hour were selected - reading files will be only enabled when both were selected
        SamplingRate; %ephys sampling rate - can be edited by user
        VideoSamplingRate = 30; %predefined sampling rate of camera
        PlotOn; %which channel(s) to display in LFP viewer
        LFPax; %object to plot LFP(s)
        line; %line object to plot threshold
        VidAx; %object to plot frames of video
        VideoImage; %video frame
        TimeStamp; %query timestamp of LFP - time slider value or seizure
        VideoStartFrame; %video around the query timestamp starts here
        VideoStopFrame; %video around the query timestamp ends here
        filtData; %filtered voltage data for seizure detection
        DataToPlay; % playback on lfp viewer. either raw or filtered/downsampled
        sd; %standard dev of filt data
        isFlipped =0; %track if the trace was flipped
        seizure; %detected seizures and their properties are kept here
        figmsg; %message window if user wants to load settings. Stored here to be able to close it if user forgets.
        RecentTime; %time to label output file with current time when the analysis occured
    end
    
    %functions that are used inside the ButtonPushed functions
    methods (Access = private)

        %Function 1 for seizure detection. Called when a channel was
        %changed. Input: all the properties of app, LFPax2 for plotting the
        %new channel, chb, which value is the selected channel
        function ChannelChanged_EwellLab(app,LFPax2, chb)

            % calculate sd of baseline for the newly selected channel
            CalculateBaseLine_EwellLab(app,chb);
            % search for the index in CurrentChannel if 8 was selected -> 1
            % if 23 was selected -> 2
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            %plot the trace using channelindex
            plot(LFPax2, app.filtData.ts(:,1), app.filtData.amp(:,ChannelIndex), 'Color', 'b')
            title(LFPax2,strcat('Channel ', num2str(app.CurrentChannels(ChannelIndex)), ' (filtered, ', app.ChannelLabel(ChannelIndex), ')' ))
            xlabel(LFPax2, 'Time (s)');
            ylabel(LFPax2, 'Amplitude ({\mu}V)')
        end

        %Function 2 for seizure detection. Called whenever the user flip
        %the trace. Input: all the properties of app, LFPax2 to plot the
        %flipped trace, chb - which channel is used, thrb - what is the
        %threshold for detection
        function FlipPushed_EwellLab(app, LFPax2,chb, thrb)
            %get the channel index based on which 
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            %flip the trace by multiplying it -1
            app.filtData.amp(:,ChannelIndex) = -app.filtData.amp(:,ChannelIndex);
            if app.isFlipped == 0
                app.isFlipped = 1;
            else
                app.isFlipped = 0;
            end
            %replot the trace
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            plot(LFPax2, app.filtData.ts(:,1), app.filtData.amp(:,ChannelIndex), 'Color', 'b')
            %run threshold changed to replot the line for the threshold (see below)
            ThresholdChanged_EwellLab(app,LFPax2,thrb);
        end

        %Function 3 for seizure detection. Called whenever the user changed
        %the threshold value. Input: all the properties of app. LFPax2 for
        %plot, thrb to calculate threshold
        function ThresholdChanged_EwellLab(app,LFPax2,thrb)
            %get the new threshold integer (N*sd)
            N = thrb.Value;
            %delete the old line from the plot
            delete(app.line)
            %plot the new line 
            app.line = line(LFPax2, 'XData',[app.filtData.ts(1,1) app.filtData.ts(end,1)], 'YData',[(N*app.sd) (N*app.sd)],'Color','g','LineWidth',1);
        end

        %function for filtering signal
        function signal_filtered = bandpassFilter_EwellLab(app,signal, Fs, Fpass)
            if exist('Fpass', 'var')
            if diff(Fpass)<=0
                error('Fpass must be in format [f_low, f_high]');
            end
            else
                error('You must pass argument Fpass. See help above.');
            end        
            
            Wn_theta = [Fpass(1)/(Fs/2) Fpass(2)/(Fs/2)]; % normalized by the nyquist frequency
            
            [btheta,atheta] = butter(3,Wn_theta);
            
            signal_filtered = filtfilt(btheta,atheta,signal);
        end

        %Function 4 for seizure detection. Called when Seizure Detection
        %button is pushed on the main GUI and whenever the user applies new filter settings: downsampling, filtering all the
        %channels that belong to the seleceted mouse.  Input: app
        %properties, LFPax2 for plot the trace, chb for which channel to
        %plot, dsb for downsampling rate, bandminb/maxb for filtering range
        function PreProcessLFP_EwellLab(app,LFPax2, chb, thrb,dsb,bandminb,bandmaxb)
            %initialize empty vector for filtered data
            app.filtData = [];
            %user-defined kHz samprate is read and convertred into Hz
            app.SamplingRate = app.SamplingRatekHzEditField.Value*1000;
            if dsb.Value <= 2*bandmaxb.Value
                msgbox('Upper limit of bandpass filter must be smaller than Sampling Rate/2!. Consider changing either DownSample or upper limit of filter.', 'Error');
            end

            %downsampling rate defined by user
            NewSamplingRate = dsb.Value;
            %how many channels we have? e.g Mouse #1 [8,23] -> two channels
            [~,c] = size(app.Data.amp);
            %downsample OE ts (:,1) and video ts (:,2)
            app.filtData.ts(:,1) = downsample(app.Data.ts(:,1), round(app.SamplingRate/NewSamplingRate));
            app.filtData.ts(:,2) = downsample(app.Data.ts(:,2), round(app.SamplingRate/NewSamplingRate));
            %ts should be in seconds to be able evaulate seizure durations
            app.filtData.ts(:,1) = app.filtData.ts(:,1)/app.SamplingRate;
            %loop on the channels to downsample and filter all the data
            for i = 1:c     
               %set every value above 2000 uV to 0 - possible artifact -
               %uncomment these two lines if you have troubles with artifactes
               % art_idx = find(abs(app.Data.amp(:,i)) > 2000);
               % app.Data.amp(art_idx,i) = 0;
                temp = downsample(app.Data.amp(:,i), round(app.SamplingRate/NewSamplingRate));
                app.filtData.amp(:,i) = bandpassFilter_EwellLab(app,temp, NewSamplingRate, [bandminb.Value bandmaxb.Value]);
            end
            
            %set the global sampling rate to the new sampling rate
            app.SamplingRate = NewSamplingRate;
            %calculate the baseline std using the filtered data (see
            %function below)on the selected channel (chb)
            CalculateBaseLine_EwellLab(app,chb);
            %plot the channel and the new threshold
            ChannelChanged_EwellLab(app, LFPax2,chb);
            ThresholdChanged_EwellLab(app,LFPax2, thrb);
            
        end
        
        %Function 5 for seizure detection. Called in the beginning and when
        %the user changes filter settings. Inputs are app properties and
        %channel id. From Maryam.
        function CalculateBaseLine_EwellLab(app,chb) 
            %get the channel index
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            %take the absolute value of the data
            absLFP = abs(app.filtData.amp(:,ChannelIndex));
            app.sd=std(absLFP); 
        end


        %function 6 for seizure detection - the actual code for detecting
        %spikes, bursts and seizures

        function SeizureDetection_EwellLabV2(app, chb,thrb,mdb,burstwinb,dsb,bandminb,bandmaxb)
            %define criteria
            SpikeThreshold = thrb.Value*app.sd;
            MinSpikeFreq = 3; %minimum spike frequency
            MergeBurstTime = burstwinb.Value; %merge bursts within this time in s
            MinSeizureDuration = mdb.Value; %minimum seizure dur in s
            %get channel index where detection will be performed
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            %detect spikes
            [~, loc] = findpeaks(app.filtData.amp(:,ChannelIndex),'MinPeakHeight',SpikeThreshold);
            %add artificial spikes to beginning and end
            spike_time = [-1; app.filtData.ts(loc,1); app.filtData.ts(end,1)+1]; %in s

            %calculate time between each spike (in seconds)
            ISI = diff(spike_time);
            
            %detect gap between bursts - calculate start and stop 
            %criteria: minimal 3 Hz freq between successive spikes
            gaps = find((ISI>1/MinSpikeFreq)==1);
            BurstStart = gaps(1:end-1)+1;
            BurstEnd = gaps(2:end);


            %split spikes into bursts
            Bursts = {}; %save bursts in vectors within this struct
            NOCB = length(BurstStart); %number of candidate burst events
            NOB = 0; %count actual bursts
            %loop on candidate events and remove the ones with one spikes
            %init
            for b = 1:NOCB
                Bursts{NOB+1} = spike_time(BurstStart(b):BurstEnd(b)); 
                    %remove bursts with individual spikes in it
                if length(Bursts{NOB+1}) == 1
                    Bursts(NOB+1) = [];
                else
                    NOB =  NOB+1;
                end
            end
            %if bursts have been found
            if NOB > 0
                %calculate interburst interval 
                IBI = zeros(NOB-1,1);
                for b = 1:NOB-1
                    IBI(b) = Bursts{b+1}(1)-Bursts{b}(end);
                end
    
                %if IBI <= MergeBurstTime, merge them together
                b =1; %counter for burst
                event = 1; %counter for while loop
                while event < NOB
                    if IBI(event)< MergeBurstTime
                        Bursts{b} = [Bursts{b}; Bursts{b+1}];
                        Bursts(b+1) = [];
                    else
                        b = b+1;
                    end
                    event = event+1;
                end
    
                %candidate seizures should be at least 10 sec long
                [~,NOCS] = size(Bursts);
                SeizureOn = [];
                SeizureOff = [];
                s = 1;
                SeizureDetection.SeizureTime ={};
                for cs = 1:NOCS
                    if Bursts{cs}(end)-Bursts{cs}(1) >= MinSeizureDuration
                        SeizureOn(s) = Bursts{cs}(1);
                        SeizureOff(s) = Bursts{cs}(end);
                        SeizureDetection.SeizureTime{s} = Bursts{cs};
                        s = s+1;
                    end
                end
    
                %output seizure detection results
                if isempty(SeizureOn) == 0                
                   
                    SeizureDetection.N = length(SeizureOn);
                    SeizureDetection.onset = SeizureOn;
                    SeizureDetection.offset = SeizureOff;
                    SeizureDetection.duration = SeizureOff-SeizureOn;
                    app.seizure.n = 1;
                else
                    app.seizure.n=0;
                    msgbox('No seizure was detected. Consider changing the settings.', 'Detection Results');
                    %remove results from the previous detection
                    app.seizure.onset = [];
                    app.seizure.offset = [];
                    app.seizure.duration = [];
                end

                %pass info to app
                app.seizure.onset = SeizureOn;
                app.seizure.offset = SeizureOff;
                app.seizure.duration=SeizureOff-SeizureOn;

            else
                msgbox('No bursts were detected.', 'Detection Results');
                app.seizure.n = 0;
                SeizureDetection.N = 0;
                %remove results from the previous detection
                app.seizure.onset = [];
                app.seizure.offset = [];
                app.seizure.duration = [];
            end

            SeizureDetection.Threshold = thrb.Value;
            SeizureDetection.SampRate = dsb.Value;
            SeizureDetection.BandPassLow = bandminb.Value;
            SeizureDetection.BandPassHigh = bandmaxb.Value;
            targetfile = strcat(app.FileList.ts(1).folder, '\Seizure Detection Output\DetectionResults_',app.Mouse.ID(app.MouseMember), '_', app.RecordingTime, '_', app.UserID, ".mat");
            save(targetfile, 'SeizureDetection')


        end

        % Function 6.2 for seizure detection. Alternative method for
        % seizure detection using spectral analysis. When checkbox is
        % marked in the detection GUI, this customized algorithm is run.
        % This function can be replaced with any other customized
        % algorithm.

        function CustomSeizureDetection(app,chb,thrb,mdb,burstwinb,dsb,bandminb, bandmaxb)
            
            NewSamplingRate = dsb.Value; 
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            %downsample LFP on the chosen channel
            dsLFP = downsample(app.Data.amp(:,ChannelIndex), round(app.SamplingRatekHzEditField.Value*1000/NewSamplingRate));     
            %create 256 ms window
            n = 256*NewSamplingRate/1000;
            dt = 1/NewSamplingRate;
            freq = 1/(dt*n)*(0:n-1)'; % assign power to each frequency based on sampling rate
            [~,loweridx] = min(abs(freq-bandminb.Value)); %lower frequency
            [~,upperidx] = min(abs(freq-bandmaxb.Value)); %higher frequency
            bandpower = zeros(length(dsLFP),2); %vector for the power

            %calculate fft for each window
            bar = waitbar(0,'Running FFT..', 'Name', 'Please wait!');
            for d = 1:length(dsLFP)-n
                start = d;
                stop = d+n-1;
                xdft = fft(dsLFP(start:stop),n);
                power = xdft.*conj(xdft);
                poweridx = d+round((stop-start)/2)-1; %map the middle of the window to the LFP
                bandpower(poweridx,1) = poweridx*dt;
                bandpower(poweridx,2) = mean(power(loweridx:upperidx)); %take the mean between the low and high freq
                if rem(d,round(0.1*length(dsLFP))) == 0
                    waitbar(d/length(dsLFP),bar, 'Running FFT...'); %display how much data is processed
                end
            end
                waitbar(0.99,bar, 'Finishing...');
                %threshold the power trace to find peaks
                avg = mean(bandpower(:,2));
                sdeviation = std(bandpower(:,2));
                N = thrb.Value; 
                threshold = avg+N*sdeviation;
                peaks = find(bandpower(:,2) > threshold);
                peak_time = bandpower(peaks,1);


                %merge detected points
                MergeBurstTime = burstwinb.Value;
                minSeizDur = mdb.Value;
                dt_peak = diff(peak_time);
                %get start and stop of the merged bursts
                burst_gaps = [0; find(dt_peak >= MergeBurstTime); length(peak_time)];

                SeizureCand = {};
                k = 1;
                %loop on the seizure candidates - if it's at least
                %minSeizDur long, count as a seizure
                SeizureOn = [];
                SeizureOff = [];
                %if bursts were detected
                if length(burst_gaps) > 2
                    for i =1:length(burst_gaps)-1
                        %only if seizure is at least 10 s
                        if peak_time(burst_gaps(i+1))-peak_time(burst_gaps(i)+1) >= minSeizDur
                            SeizureCand{k,1} = burst_gaps(i)+1:burst_gaps(i+1);
                            SeizureOn(k) = peak_time(burst_gaps(i)+1);
                            SeizureOff(k) = peak_time(burst_gaps(i+1));
                            SeizureCand{k,2} = peak_time(burst_gaps(i)+1:burst_gaps(i+1));
                            SeizureCand{k,3} = dsLFP(peaks(burst_gaps(i)+1:burst_gaps(i+1)));
                            k = k+1;
                        end
                    end

                    %output seizure detection results
                    if isempty(SeizureOn) == 0

                        SeizureDetection.N = length(SeizureOn);
                        SeizureDetection.onset = SeizureOn;
                        SeizureDetection.offset = SeizureOff;
                        SeizureDetection.duration = SeizureOff-SeizureOn;
                        app.seizure.n = 1;
                    else
                        app.seizure.n=0;
                        msgbox('No seizure was detected. Consider changing the settings.', 'Detection Results');
                        %remove results from the previous detection
                        app.seizure.onset = [];
                        app.seizure.offset = [];
                        app.seizure.duration = [];
                    end

                    %pass info to app
                    app.seizure.onset = SeizureOn;
                    app.seizure.offset = SeizureOff;
                    app.seizure.duration=SeizureOff-SeizureOn;
                else
                    msgbox('No bursts were detected.', 'Detection Results');
                    app.seizure.n = 0;
                    SeizureDetection.N = 0;
                    %remove results from the previous detection
                    app.seizure.onset = [];
                    app.seizure.offset = [];
                    app.seizure.duration = [];
                end
                
                %save output
                SeizureDetection.Mode = "custom";
                SeizureDetection.Threshold = thrb.Value;
                SeizureDetection.SampRate = dsb.Value;
                SeizureDetection.BandPassLow = bandminb.Value;
                SeizureDetection.BandPassHigh = bandmaxb.Value;
                targetfile = strcat(app.FileList.ts(1).folder, '\Seizure Detection Output\DetectionResults_',app.Mouse.ID(app.MouseMember), '_', app.RecordingTime, '_', app.UserID, ".mat");
                save(targetfile, 'SeizureDetection')
                close(bar)


        end


        % Function 7 for seizure detection. Called when Seizure Detection button is pushed in the
        % subGUI. All the steps of detection are organized within this
        % funcrtion. Inputs: app properties, plot, channel, threshold,
        % mindur
        function SeizureDetectionPushed_EwellLab(app, LFPax2,cbx,chb,thrb,mdb,burstwinb,dsb,bandminb,bandmaxb)
            
            %write all the settings into mat file
            settingsfile = strcat(app.FileList.ts(1).folder, '\Seizure Detection Output\Settings_',app.Mouse.ID(app.MouseMember),'_', app.RecordingTime, '_', app.UserID,'.mat');
            channel = chb.Value;
            threshold = thrb.Value; 
            mindur = mdb.Value;
            flip = app.isFlipped;
            save(settingsfile, 'channel', 'threshold', 'mindur', 'flip');
            
            %spike detection
            %peaks = SpikeDetection_EwellLab(app,chb,thrb);
            %seizure detection
            if cbx.Value == 0
                SeizureDetection_EwellLabV2(app, chb,thrb,mdb,burstwinb,dsb,bandminb,bandmaxb);
            else
                CustomSeizureDetection(app,chb,thrb,mdb,burstwinb,dsb,bandminb, bandmaxb); %if custom algorithm is used
            end
            %plot detected seizures
            ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
            plot(LFPax2, app.filtData.ts(:,1), app.filtData.amp(:,ChannelIndex), "Color", 'b')
            xlabel(LFPax2, 'Time (s)');
            ylabel(LFPax2, 'Amplitude ({\mu}V)')
            title(LFPax2,strcat('Detected seizures (', num2str(length(app.seizure.onset)), ')' ))
            hold(LFPax2, 'on')
            %draw line between each start and end
            for k = 1:length(app.seizure.onset)
                if cbx.Value == 0
                    yval =  threshold*app.sd;
                else
                    yval = 0;
                end
                plot(LFPax2, [app.seizure.onset(k) app.seizure.offset(k)] , [threshold*app.sd threshold*app.sd], 'Marker','*', 'Color', 'r')
            
            end
            hold(LFPax2, 'off')

        end

        %Function 8 for seizure detection. Called when user wants to load
        %settings from an existing file. If the app finds an output folder,
        %it asks the user whether wants to read the settings from file (small pop-up window is used)
        %Inputs: app properties, plot, pop-upwindow(figmsg), channel,
        %threshold, mindur
        function ReadSettings_EwellLab(app, LFPax2, chb,thrb,mdb)
                %open up directory browser
                [file,path] = uigetfile;
                %open file that the user selected
                params = load(strcat(path,file));
                %check if the right setting file was open
                isChannelHere = find(~cellfun(@isempty,strfind(chb.Items,params.channel)));
                if isempty(isChannelHere) ~= 0
                    msgbox('Wrong setting file was load! Choose another file!', 'Error')
                end
                %load the params
                chb.Value = params.channel;
                thrb.Value = params.threshold;
                mdb.Value = params.mindur;
                if isfield(params,'flip')
                    app.isFlipped = params.flip;
                    if app.isFlipped == 1
                        ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
                        %flip the trace by multiplying it -1
                        app.filtData.amp(:,ChannelIndex) = -app.filtData.amp(:,ChannelIndex); 
                    end
                end
                %plot channel and threshold
                ChannelChanged_EwellLab(app,LFPax2, chb);
                ThresholdChanged_EwellLab(app,LFPax2,thrb);
                %close the pop-up window
                close(app.figmsg);
               
        end
        
        %Function 9 for seizure detection. Called when a user accepts the
        %results of seizure detection.
        function AcceptButtonPushed_EwellLab(app,fig,chb)
            %if trace was flipped for detection - flip it back 
            if app.isFlipped ==1
                ChannelIndex = find(app.CurrentChannels == str2num(chb.Value));
                app.filtData.amp(:,ChannelIndex) = -app.filtData.amp(:,ChannelIndex);
            end

            %from now, play the filtered data
            app.DataToPlay.ts = app.filtData.ts;
            app.DataToPlay.amp = app.filtData.amp;
            app.SamplingRate =1; %this is just a formal solution - video play will be divided by sampling rate, but for the filtered data it is already happened during preprocessing
            %close the detection subGUI
            close(fig);
            if ishghandle(app.figmsg) == 1
                close(app.figmsg);
            end


            %if no seizure case was accepted, write an output and send a
            %message to the user to move to next mouse/file
            if app.seizure.n == 0
                ScoredSeizures = app.seizure.n;
                targetdir = strcat(app.FileList.ts(1).folder, '\Seizure Detection Output\');
                targetfile = strcat(targetdir,'Scoring_', app.Mouse.ID(app.MouseMember), '_', app.RecordingTime, '_', app.UserID, ".mat");
                save(targetfile, 'ScoredSeizures');
                msgbox("No seizure was detected. Results were saved.", "Message")
            else

                %plot it in the main GUI
                PlotSeizure_EwellLab(app);
            end
            
            if exist('app.figmsg', 'var') == 1
                disp('here')
                close(app.figmsg)
            end


        end

        %Function for plotting the seizures for evaluation in the main GUI.
        %Called whenever the user replot the recent seizure/goes to prev or
        %next seizure.
        function PlotSeizure_EwellLab(app)
            
            %update video title 
            vidtitle = strcat("Seizure ", num2str(app.seizure.n));
            sgtitle(app.VideoPlaybackPanel, vidtitle, 'Color', 'cyan', 'Backgroundcolor', 'white')

            %get the # of the seizure
            N = app.seizure.n;
            %get the onset time and subtract the LFP time window, set by
            %the user. 
            startTime = app.seizure.onset(N)-app.TimeWindowsEditField.Value;
            [~,startIndex] = min(abs(app.filtData.ts(:,1)-startTime));
            %no negative time
            if startIndex < 1
                startIndex =1;
            end
            %get the offset and add the LFP time window, set by
            %the user. Plot range: offset-timewindow:onset+timewindow
            stopTime = app.seizure.offset(N)+app.TimeWindowsEditField.Value;
            [~,stopIndex] = min(abs(app.filtData.ts(:,1)-stopTime));
            %no overflowing time
            if stopIndex > length(app.filtData.ts(:,1))
                stopIndex = length(app.filtData.ts(:,1));
            end  
            %which channels were selected by user? (tickboxes on the left
            %side) loop on the list
            for k=1:length(app.CurrentChannels)
                rb_field = strcat('rb',num2str(k));
                Ticks(k) = app.rbs.(rb_field).Value;
            end
            %find the channel that were tick marked
            app.PlotOn = find(Ticks == 1);


            %get limits of y-axis - the greatest maximum and minimum
            mins = min(app.filtData.amp(startIndex:stopIndex,:));
            ymin = min(mins);
            maxs = max(app.filtData.amp(startIndex:stopIndex,:));
            ymax = max(maxs);
            
            %loop on these channels and plot them on subplots (w/ marking the seizure)
            for j = 1:length(app.PlotOn)
                  ax_field = strcat('ax',num2str(j));
                  app.LFPax.(ax_field) = subplot(length(app.PlotOn),1,j, 'Parent', app.LFPViewerPanel);
                  %clear subplot
                  cla(app.LFPax.(ax_field));
                  plot(app.LFPax.(ax_field),app.filtData.ts(:,1), app.filtData.amp(:,app.PlotOn(j)));
                  hold(app.LFPax.(ax_field), 'on')
                  plot(app.LFPax.(ax_field), [app.seizure.onset(app.seizure.n) app.seizure.offset(app.seizure.n)] , [0 0], 'Marker','*', 'Color', 'r')
                  title(app.LFPax.(ax_field),strcat("Ch ", num2str(app.CurrentChannels(app.PlotOn(j))), app.ChannelLabel(app.PlotOn(j))));
                  xlim(app.LFPax.(ax_field), [startTime stopTime])
                  ylim(app.LFPax.(ax_field),[ymin ymax]);
                  xlabel(app.LFPax.(ax_field), 'Time (s)')
                  ylabel(app.LFPax.(ax_field), 'Amplitude ({\mu}V)')
                  hold(app.LFPax.(ax_field), 'off')
            end

            %lock x-axis of the first two channels
            if length(app.PlotOn) == 2
                linkaxes([app.LFPax.('ax1'),app.LFPax.('ax2') ],'x');
            end
            %plot the number of the recent seizure and the overall number
            %of seizures
            sgtitle(app.LFPViewerPanel,strcat("Seizure ", num2str(N), "/", num2str(length(app.seizure.onset)), " Dur = ", num2str(app.seizure.duration(app.seizure.n)), " s"));
            
            %Video. Start and stop frame are defined where the onset and
            %offset start. Viewing time window is expanded in PlayVideo
            %func (see below)
            [~,startVid] = min(abs(app.filtData.ts(:,1)-app.seizure.onset(N)));
            [~,stopVid] = min(abs(app.filtData.ts(:,1)-app.seizure.offset(N)));
            app.VideoStartFrame = app.filtData.ts(startVid(1),2);
            app.VideoStopFrame = app.filtData.ts(stopVid(1),2);
        %   PlayVideo_EwellLab(app);

        end

        %Function for playing video chunks. Called when the user either
        %plots a seizure or move on the time slidebar. Video can be long,
        %it's important the user won't press any other button - tasks will
        %be accumulated. Therefore buttons are disabled during display.
        function PlayVideo_EwellLab(app)
            app.VideoSwitch.Value = "On";
            %Disable buttons for plotting and video display
            IsSeiz = 0;
            app.ChooseWorkingDirectoryButton.Enable = 'off';
            app.ReadFilesButton.Enable = 'off';
            app.SeizureDetectionButton.Enable = 'off';
            app.PlotLFPButton.Enable = 'off';
            app.TimeminSlider.Enable = 'off';
            app.PlayVideoButton.Enable = 'off';
            %Mark if this is seizure evaluation and turn off those buttons
            %too.
            if app.PreviousSeizureButton.Enable == 'on'
                IsSeiz = 1;
                app.SaveButton.Enable = 'off';
                app.PreviousSeizureButton.Enable = 'off';
                app.NextSeizureButton.Enable = 'off';
                app.ReplotButton.Enable = 'off';
            end
            
            %Defining the start of the playback - query timestamp such as
            %seizure onset or value on slidebar, subtracted the video
            %timewindow value that is defined by user in seconds.
            PlayBackStart = round(app.VideoStartFrame-(app.TimeWindowsEditField.Value*app.VideoSamplingRate));
            %no negative frames
            if PlayBackStart < app.DataToPlay.ts(1,2)
                PlayBackStart = app.DataToPlay.ts(1,2);
            end
            %same with stop
            PlayBackStop = round(app.VideoStopFrame+(app.TimeWindowsEditField.Value*app.VideoSamplingRate));
            %frame should not exceed the max number of frames
            if PlayBackStop > app.DataToPlay.ts(end,2)
                PlayBackStop = app.DataToPlay.ts(end,2);
            end


            NLine = round((PlayBackStop-PlayBackStart)/app.VideoSamplingRate); %# of lines to draw on LFP while video is played
            LFP_start =zeros(NLine,1);
            LFP_end = zeros(NLine,1);
            i = 1;

            %there is a maximum frame error defined the max diff between
            %frames
            frameError = max(diff(app.DataToPlay.ts(:,2)));
            LFPspeed = 30; %update lfp axis after every 30 frames
            %initialize lines 
            for f = PlayBackStart:LFPspeed:PlayBackStop
                idx = find(abs(app.DataToPlay.ts(:,2)-f)<frameError);
                LFP_start(i) = app.DataToPlay.ts(idx(1),1)/(app.SamplingRate);
                stopVidFrame = f+app.VideoSamplingRate;
                if  stopVidFrame>PlayBackStop
                    stopVidFrame = PlayBackStop;
                end
                idx = find(abs(app.DataToPlay.ts(:,2)-(stopVidFrame))<frameError);
                LFP_end(i) = app.DataToPlay.ts(idx(1),1)/(app.SamplingRate);
                i = i+1;
            end
%            %define the subplot for plotting & adjust xlim - always show
%            30 s of the data
             %clear the plot and replot the traces
            Nsubplot = length(app.PlotOn);
            ax_field = strings(Nsubplot,1);
            for j = 1:Nsubplot
                ax_field(j) = strcat('ax',num2str(j));
                hold(app.LFPax.(ax_field(j)), 'on')
                cla(app.LFPax.(ax_field(j)));
                plot(app.LFPax.(ax_field(j)),app.DataToPlay.ts(:,1)/app.SamplingRate, app.DataToPlay.amp(:,app.PlotOn(j)));title(app.LFPax.(ax_field(j)),strcat("Ch ", num2str(app.CurrentChannels(app.PlotOn(j))), app.ChannelLabel(app.PlotOn(j))));
                xlim(app.LFPax.(ax_field(j)), [LFP_start(1) LFP_start(1)+30])
            end

            %during playback, string is displayed
            sgtitle(app.VideoPlaybackPanel, 'Playing...',  'Color', '#EDB120', 'BackgroundColor', 'white')
            textOn = 0;
            xticks(app.VidAx, []);
            yticks(app.VidAx, []);
            lag = app.VideolagSpinner.Value;
            %loop on the frames and plot them
            LineCounter = 1;
            tic;
           %now the actual video playback - loop on every frame
           for frame = PlayBackStart:PlayBackStop

                 if strcmp(app.VideoSwitch.Value,"Off") == 1
                     break;
                 end
                 % move LFP window after 30 s
                 if rem(frame, app.VideoSamplingRate*30)==0
                        for j = 1:Nsubplot
                            xlim(app.LFPax.(ax_field(j)), [LFP_start(LineCounter) LFP_start(LineCounter)+30])
                        end
                 end
                 % draw line on LFP in every second 
                 if rem(frame,LFPspeed) == 0 && LineCounter <= NLine
                     if frame >= app.VideoStartFrame && frame <= app.VideoStopFrame
                         for j = 1:Nsubplot
                             plot(app.LFPax.(ax_field(j)), [LFP_start(LineCounter) LFP_end(LineCounter)], [0 0], 'r-', 'LineWidth',4);
                         end
                     else
                         for j = 1:Nsubplot
                             plot(app.LFPax.(ax_field(j)), [LFP_start(LineCounter) LFP_end(LineCounter)], [0 0], 'Color', '#EDB120', 'LineWidth',4);
                         end
                     end
                     LineCounter = LineCounter+1;
                 end
                %if we are between onset and offset/value on slidebar, display three stars so
                %the user know they are watching the interesting part
                if frame >= app.VideoStartFrame && frame <= app.VideoStopFrame && textOn == 0
                   sgtitle(app.VideoPlaybackPanel, '*** Seizure ***',  'Color', 'red', 'FontSize', 20, 'BackgroundColor', 'white')
                   textOn = 1;
                end
                %if it's over, remove stars
                if textOn == 1 && frame >app.VideoStopFrame
                    sgtitle(app.VideoPlaybackPanel, 'Playing...', 'Color', '#EDB120', 'BackgroundColor', 'white')
                    textOn = 0;
                end
                %plot frame
                app.VideoImage.CData = read(app.Data.video,frame);  
                %wait 25 ms in each iteration - otherwise playback is too
                %fast
                drawnow limitrate
                java.lang.Thread.sleep(lag)     
            end
            T = toc;
            sgtitle(app.VideoPlaybackPanel, strcat("Elapsed time is ", num2str(T), " seconds." ), 'Color', 'cyan', 'Backgroundcolor', 'white')
            
            %next time, plot can be overwritten
            for j = 1:Nsubplot
                hold(app.LFPax.(ax_field(j)), 'off')
            end
            %Enable buttons, also seizure buttons if this is a seizure
            %detection
            app.ChooseWorkingDirectoryButton.Enable = 'on';
            app.ReadFilesButton.Enable = 'on';
            app.PlotLFPButton.Enable = 'on';
            app.TimeminSlider.Enable = 'on';
            app.PlayVideoButton.Enable = 'on';
            app.SeizureDetectionButton.Enable = 'on';
            if IsSeiz == 1
                app.SaveButton.Enable = 'on';
                app.PreviousSeizureButton.Enable = 'on';
                app.NextSeizureButton.Enable = 'on';
                app.ReplotButton.Enable = 'on';
            end


        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% when GUI opens, wait some time to load everything and then maximize the size of it
            pause(1);
            app.SeizureAnalysis10UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: ChooseWorkingDirectoryButton
        function ChooseWorkingDirectoryButtonPushed(app, event)
            
            % clean a few variables in case this is not the first folder to
            % choose
            app.FileList = [];
           % app.MouseMember=1;
            %read user id 
            prompt = 'Enter User ID';
            dlgtitle = 'ID';
            dims = [1 35];
            app.UserID = string(inputdlg(prompt,dlgtitle,dims));
            %when the button is pushed, directory browser pops up where
            %user has to select a working directory (where the amp and
            %video data lives). In that folder, you need to have a
            %configuration file - that can be the only txt file in that
            %folder!
            app.FileList; % for storing file names
            selpath = uigetdir;
            app.path.Value = selpath;
            %read all the file names:configuration, ts data, amp data,
            %video
            app.FileList.config = dir(strcat(selpath,'\*.txt'));
            app.FileList.ts = dir(strcat(selpath,'\ts*.bin'));
            app.FileList.amp = dir(strcat(selpath,'\amp*.bin'));
            app.FileList.video = dir(strcat(selpath,'\*.avi'));
            
            
            %Open the configuration file. Format: Port, ID, Channels. Each
            %mouse is a row.
            id = fopen(strcat(app.FileList.config.folder, '\', app.FileList.config.name),'r');
            if id == -1
                msgbox('No configuration file was found!', 'Error');
            else
                app.Data.configuration = textscan(id,'%s %s %s', [Inf Inf], 'HeaderLines',1, 'Delimiter', ',');
                fclose(id);
            end
            
            %Read the number of rows in config file = defining the number of the mice
            %used in the experiment
            [NumberOfMice, ~] = size(app.Data.configuration{3});
            %initalizing a matrix to store the channel index for each mice.
            %Maximum # of channels is 32 - set higher if needed
            app.Mouse.Channels = zeros(32, NumberOfMice);
            app.ChannelLabel = strings(32,1);
            %as we use two channels, two labels are defined
            app.ChannelLabel(1) = " (right)";
            app.ChannelLabel(2) = " (left)";

            for i=1:NumberOfMice
                %convert the text into vector For e.g [8,23] means we have 30 zeros and two
                %ones at 8 and 23 for Mouse 1 in the Channels vector
                temp = cell2mat(textscan(app.Data.configuration{3}{i}, '%d'));
                app.Mouse.Channels(temp,i) = 1; 
            end
            %mouse id, number of channels that were used, and OE recording port are stored in
            %mouse struct
            app.Mouse.ID = app.Data.configuration{2};
            app.Mouse.NumberOfChannels = sum(app.Mouse.Channels);
            app.Mouse.Port = app.Data.configuration{1};

            %Update dropdown menu in GUI
            %Enable user to select mouse
            app.MouseIDDropDown.Enable = 'on';
            %list the mouse IDs in the dropdown 
            app.MouseIDDropDown.Items = {'Select!', app.Data.configuration{2}{1:end}};
            app.MouseIDDropDown.ItemsData= cellstr(string(0:NumberOfMice));
            %Enable user to select hour/file
            app.DateDropDown.Enable = 'on';
            %assign dropdown item to date of recording
            DateItems = strings(length(app.FileList.ts),1);
            for d = 1:length(app.FileList.ts)
                DateItems(d) = string(app.FileList.ts(d).name(3:end-4));
            end
            app.DateDropDown.Items = ['Select!'; DateItems];      
            app.DateDropDown.ItemsData= cellstr(string(0:length(app.FileList.ts)));
        end

        % Value changed function: MouseIDDropDown
        function MouseIDDropDownValueChanged(app, event)
            %Called when user select a mouse
            app.IsSelected(1) = 1; %flag that mouse id was selected
            %if user has selected mouse and hour, enable to read file
            if sum(app.IsSelected) == 2
                app.ReadFilesButton.Enable = 'on';
            end
            
            %update mousemember - number of mouse that is used
            app.MouseMember = round(str2double(app.MouseIDDropDown.Value));
            %if user accidently didnt select any mouse, it'll set to mouse
            %1
            if app.MouseMember == 0
                msgbox('Please select mouse id!', 'Error');
            end
            %update current channels - channels that belongs to the
            %selected mouse
            app.CurrentChannels = find(app.Mouse.Channels(:,app.MouseMember)==1);

            
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            %Called when user selected an hour/file
            app.IsSelected(2) = 1; %flag that date of recording was selected
            %if mouse and hour were selected, enable reading the file
            if sum(app.IsSelected) == 2
                app.ReadFilesButton.Enable = 'on';
            end
            

            %update list member - the number of the file to be used. If
            %user accidentally did not select anythin, first file will be selected 
            app.ListMember = round(str2double(app.DateDropDown.Value));
            if app.ListMember == 0
                msgbox('Please select date!', 'Error');
            end
            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
              msgbox(strcat("Reading " , app.DateDropDown.Items{app.ListMember+1}, "  ", app.MouseIDDropDown.Items{app.MouseMember+1}));
              %define current time to create a timestamp when the scoring
              %happened
              app.RecentTime = clock;
              %clear variables in case the gui was already in use
              app.DataToPlay = [];
              app.filtData = [];
              app.seizure = [];
             %disable buttons 
             app.SeizureDetectionButton.Enable = 'off';
             app.LFPlooksasButtonGroup.Enable = 'off';
             app.DropDown.Enable = 'off';
             app.BehaviorlooksasButtonGroup.Enable = 'off';
             app.CommentTextArea.Enable = 'off';
             app.SaveButton.Enable = 'off';
             app.PreviousSeizureButton.Enable = 'off';
             app.ReplotButton.Enable = 'off';
             app.NextSeizureButton.Enable = 'off';
             app.TimeminSlider.Enable = 'off';
             app.PlayVideoButton.Enable = 'off';
              %get sampling rate from user (kHz to Hz)
              app.SamplingRate = app.SamplingRatekHzEditField.Value*1000;
              %Called when user select the read files button
              %Buttons for next steps are enabled
              app.PlotLFPButton.Enable = 'on';
              %read the selected ts using ListMember
              f = waitbar(0,'Loading Ephys data...', 'Name', 'Please wait!');
              id = fopen(strcat(app.FileList.ts(app.ListMember).folder, '\', app.FileList.ts(app.ListMember).name));
              %timestamp is stored in 2 columns: amp and video
              data.ts = fread(id, [2, Inf], 'uint32');
              data.ts=transpose(data.ts);
              %shift video frames to 1
              data.ts(:,:) = data.ts(:,:)-data.ts(1,:)+1;
              fclose(id);

             
              %read amplifier data
              id = fopen(strcat(app.FileList.amp(app.ListMember).folder, '\', app.FileList.amp(app.ListMember).name));
              %amp file contains all the channels, not just the one that
              %the user selected
              tempAmpData = fread(id, [sum(app.Mouse.NumberOfChannels), Inf], 'uint16');
              tempAmpData=transpose(tempAmpData);
              fclose(id);

              %read the selected video
              waitbar(.5,f,'Loading Video data...');
              data.video = VideoReader(strcat(app.FileList.video(app.ListMember).folder, '\', app.FileList.video(app.ListMember).name));
          
              waitbar(.99,f, 'Finishing');
              %So then which channels to use? Determined by mouse ID
              if app.MouseMember > 1
                  start = sum(app.Mouse.NumberOfChannels(1:app.MouseMember-1))+1;
                  stop = start+app.Mouse.NumberOfChannels(app.MouseMember)-1;
              else
                  start = 1;
                  stop = app.Mouse.NumberOfChannels(1);
              end

              %Transform amplifier data
              i= 1;
              %loop on channels which belongs to the selected mouse, load
              %data struct that'll ONLY contain these channels
              for ch = start:stop
              %uint16 datatype [0 65535]  - shift data to 0 which is (65535+1)/2 
              data.amp(:,i) =tempAmpData(:,ch)-32768;
              % 0.195 uV/bit scale factor to transform data into uV
              data.amp(:,i) =data.amp(:,i)*0.195; 
              i = i+1;
              end
              %set struct as an app property
              app.Data = data;

              
            %Update channel selection panel where user can select channeles
            %to plot
            PanelPos = app.SelectChannels.Position;
            delete(app.SelectChannels)
            app.SelectChannels = uipanel(app.SeizureAnalysis10UIFigure);
            app.SelectChannels.Position = PanelPos;
            app.SelectChannels.Scrollable = 'on';            
            for k=1:length(app.CurrentChannels)
                rb_field = strcat('rb',num2str(k));
                rbPos = [10 app.SelectChannels.Position(4)+20-k*25  140 20];
                app.rbs.(rb_field) = uicheckbox(app.SelectChannels,'Position',rbPos);
                app.rbs.(rb_field).Text = strcat("Ch ", num2str(app.CurrentChannels(k)));
                app.rbs.(rb_field).Value = 1;
            end
            app.SelectChannels.Title = strcat( " Port ", app.Mouse.Port(app.MouseMember), "  Mouse ", app.Mouse.ID(app.MouseMember));
            close(f)

            %save time data of the current file
            app.RecordingTime = app.FileList.ts(app.ListMember).name(3:end-4);


            %check if length(ts) == length(amp). If not, chunk the longer
            %one and send message to user
            time_length = length(app.Data.ts(:,1));
            amp_length = length(app.Data.amp(:,1));
            if  time_length>amp_length 
                diff=(time_length-amp_length);
                app.Data.ts(length(app.Data.amp(:,1))+1:end,:) = [];
                msgbox(strcat("There are ", num2str(diff), " more data point in timestamp file. Data was chunked to match amp file, but consider double-checking your data."), "Warning")
            elseif amp_length > time_length
                diff=(amp_length-time_length);
                app.Data.amp(length(app.Data.ts(:,1))+1:end,:) = [];
                msgbox(strcat("There are ", num2str(diff), " more data point in amp file. Data was chunked to match timestamp file, but consider double-checking your data."), "Warning")
            end

            %reset scoring buttons - it may be not the first scoring
            app.BehaviorlooksasButtonGroup.Enable = 'off';
            app.Stage1Button.Value = true;
            app.LFPlooksasButtonGroup.Enable='off';
            app.SeizureButton.Value = true;
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Left hemisphere';
            app.CommentTextArea.Enable = 'off';
            app.CommentTextArea.Value = '';
            app.SaveButton.Enable = 'off';
        end

        % Button pushed function: PlotLFPButton
        function PlotLFPButtonPushed(app, event)
 
              %enable timeslider for user
              app.PlotLFPButton.Enable = 'off';
              %divide slider ticks based on data length
              LastTick = length(app.Data.ts(:,1))/app.SamplingRate/60;
              Tickstep = round(2*LastTick/60);
              app.TimeminSlider.MajorTicks = 0:Tickstep:LastTick;
              app.TimeminSlider.Limits = [0 LastTick];
              %enable video playback button
              
    
              %Which channels were selected by user to Plot? Counting the
              %ticks
              for k=1:length(app.CurrentChannels)
                rb_field = strcat('rb',num2str(k));
                Ticks(k) = app.rbs.(rb_field).Value;
              end
              %index of channels to plot
              app.PlotOn = find(Ticks == 1);

              %plot this channels
              app.LFPViewerPanel.AutoResizeChildren = 'off';
                            %pass rawdata to lfptoplay
              app.DataToPlay.ts = app.Data.ts;
              
              %loop on channels to plot, show next to each other (works well for low channel num)
              for j = 1:length(app.PlotOn)
                  app.DataToPlay.amp(:,j) = app.Data.amp(:,app.PlotOn(j));
                  ax_field = strcat('ax',num2str(j));
                  app.LFPax.(ax_field) = subplot(length(app.PlotOn),1,j, 'Parent', app.LFPViewerPanel);
                  %clear subplot
                  cla(app.LFPax.(ax_field));
                  plot(app.LFPax.(ax_field),app.Data.ts(:,1)/app.SamplingRate, app.Data.amp(:,app.PlotOn(j)));title(app.LFPax.(ax_field),strcat("Ch ", num2str(app.CurrentChannels(app.PlotOn(j))), app.ChannelLabel(app.PlotOn(j))));
                  xlabel(app.LFPax.(ax_field), 'Time (s)')
                  ylabel(app.LFPax.(ax_field), 'Amplitude ({\mu}V)')
              
              end
              %link x axes of the first channels if you have two channels
              if length(app.PlotOn) == 2
                linkaxes([app.LFPax.('ax1'),app.LFPax.('ax2') ],'x');
              end
              sgtitle(app.LFPViewerPanel,"Raw LFP")
              
              %initialize the video playback
              app.VideoPlaybackPanel.AutoResizeChildren = 'off';
              app.VidAx = axes(app.VideoPlaybackPanel, 'Position',[0 0 1 1]);
              app.Data.video = VideoReader(strcat(app.FileList.video(app.ListMember).folder,'\',app.FileList.video(app.ListMember).name ));
              h= app.Data.video.height;
              w= app.Data.video.width;
              app.VideoImage = imagesc(app.VidAx,linspace(0,1,w),linspace(0,1,h),zeros(h,w));
              app.VideoImage.CData = read(app.Data.video,1);
              app.TimeminSlider.Enable = 'on';
              app.PlayVideoButton.Enable = 'on';
              app.SeizureDetectionButton.Enable = 'on';
              app.PlotLFPButton.Enable = 'on';
        end

        % Value changed function: TimeminSlider
        function TimeminSliderValueChanged(app, event)
            %Function called when timeslider value changed
            %query timestamp got from Timeslider
            %get timestamp value - slidebar is on minutes - need to be
            %converted to data sample 
            app.TimeStamp = round(app.TimeminSlider.Value*app.SamplingRate*60);
            %calculate time window around the query timestamp -
            %user-defined time window
            TimeWindow= app.TimeWindowsEditField.Value*app.SamplingRate;     
            WindowStart = round(app.TimeStamp-TimeWindow/2);            
            if WindowStart < 1 
                WindowStart = 1;
            end
            WindowStop = round(app.TimeStamp+TimeWindow/2);
            if WindowStop > app.Data.ts(end,1)
                WindowStop = app.Data.ts(end,1);
            end
            %set xlim based on the time window, set ylim based on min and
            %max
            for j = 1:length(app.PlotOn)
                  ax_field = strcat('ax',num2str(j));
                  xlim(app.LFPax.(ax_field), [WindowStart/app.SamplingRate WindowStop/app.SamplingRate])
                  ylim(app.LFPax.(ax_field), [min(app.Data.amp(WindowStart:WindowStop,j)) max(app.Data.amp(WindowStart:WindowStop,j))])
            end
             
            %%playback the corresponding videochunk
            %set start and stop frame (window will be expanded in PlayVideo func)
            app.VideoStartFrame = app.Data.ts(app.TimeStamp,2);
            app.VideoStopFrame = app.Data.ts(app.TimeStamp,2);
            PlayVideo_EwellLab(app);

            
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
            %Function called when user wants to playback video chunk
            

            %turn off seizure detection buttons & save previous value
            zeroStates = [app.PreviousSeizureButton.Enable app.ReplotButton.Enable app.NextSeizureButton.Enable];
            app.PreviousSeizureButton.Enable = 'off';
            app.ReplotButton.Enable = 'off';
            app.NextSeizureButton.Enable = 'off';

            %play the video again (start and stop frame are saved prior)
            PlayVideo_EwellLab(app);
        
            %set back to previous value
            app.PreviousSeizureButton.Enable = zeroStates(1);
            app.ReplotButton.Enable = zeroStates(2);
            app.NextSeizureButton.Enable = zeroStates(3);


        end

        % Button pushed function: SeizureDetectionButton
        function SeizureDetectionButtonPushed(app, event)
             %Function when user pushes SeizureDetection. It creates a
             %subGUI
            
             %enable buttons for the evaluation
             app.LFPlooksasButtonGroup.Enable = 'on';
             app.DropDown.Enable = 'on';
             app.BehaviorlooksasButtonGroup.Enable = 'on';
             app.CommentTextArea.Enable = 'on';
             app.SaveButton.Enable = 'on';
             app.PreviousSeizureButton.Enable = 'on';
             app.ReplotButton.Enable = 'on';
             app.NextSeizureButton.Enable = 'on';
   

            %create subgui for settings
            fig = uifigure("Name", "Seizure Detection","Position", [450 200 1200 600]);
            LFP = uipanel(fig,'Title', 'LFP viewer', 'Position', [round(fig.Position(3)/4+10) 0 round(3*fig.Position(3)/4) fig.Position(4)]);
            LFPax2 = axes(LFP, "XLim", [app.Data.ts(1,1) app.Data.ts(end,1)]);
            
            settings = uipanel(fig,'Title', 'Settings', 'Position', [0 0 round(fig.Position(3)/4) fig.Position(4)]);
            cbx = uicheckbox(fig, 'Text',"Use Custom Algorithm",'Value', 0,'Position',[10 settings.Position(4)-60 200 50]);
            chlabel = uilabel(settings, "Text","Channel", "Position",[10 cbx.Position(2)-30 100 50]);
            thrlabel = uilabel(settings, "Text","Threshold", "Position",[10 chlabel.Position(2)-50 100 50]);
            mdlabel = uilabel(settings, "Text","Min Dur (s)", "Position",[10 chlabel.Position(2)-100 100 50]);
            burstlabel =uilabel(settings, "Text","Merge Bursts (s)", "Position",[10 mdlabel.Position(2)-50 100 60]);
 
            chb = uidropdown(settings, "Position",[chlabel.Position(1)+80 chlabel.Position(2)+15 100 20],  'ValueChangedFcn',@(chb,event) ChannelChanged_EwellLab(app,LFPax2,chb));
            chb.Items = {};
            for i = 1:length(app.CurrentChannels)
                chb.Items(i) = {num2str(app.CurrentChannels(i))};
            end
            chb.Value = chb.Items(1);

            %calculate baseline sd (Maryam)
            
            %define buttons and their actions
            %spinner for setting threshold, changes between from 1 to 10
            thrb = uispinner(settings, "Position",[chb.Position(1) chb.Position(2)-50 100 20], 'Limits',[1 100],'Value',3, "ValueChangedFcn", @(thrb,event) ThresholdChanged_EwellLab(app,LFPax2,thrb));
            %button to flip trace
            flip = uibutton(settings, "Text", "Flip LFP", "Position", [10 burstlabel.Position(2)-50 180 30], "ButtonPushedFcn", @(flip,event) FlipPushed_EwellLab(app,LFPax2,chb,thrb));
            dslabel = uilabel(settings, "Text","DownSample (Hz)", "Position",[10 flip.Position(2)-80 100 50]);
            bandlabel = uilabel(settings, "Text","FreqRange (Hz)", "Position",[10 dslabel.Position(2)-50 100 50]); 
            %field to set minimal duration
            mdb = uieditfield(settings, 'numeric', "Position",[chb.Position(1) thrb.Position(2)-50 100 20], 'Value', 10);  
            %field to set time window where bursts are merged
            burstwinb = uieditfield(settings, 'numeric', "Position",[chb.Position(1)+20 mdb.Position(2)-45 80 20], 'Value', 2.5); 
            %field to set downsampling
            dsb = uieditfield(settings, 'numeric', "Position",[dslabel.Position(1)+120 dslabel.Position(2)+15 50 20], 'Value', 500); 
            %fields to set min and max bandpass filter
            bandminb = uieditfield(settings, 'numeric', "Position",[chb.Position(1)+20 dsb.Position(2)-50 50 20], 'Value', 3);
            bandmaxb = uieditfield(settings, 'numeric', "Position",[chb.Position(1)+80 dsb.Position(2)-50 50 20], 'Value', 50);
            %button for applying the new filter settings
            filtset = uibutton(settings, "Text", "Apply Filter Settings", "Position", [10 bandlabel.Position(2)-50 180 30], "ButtonPushedFcn", @(filtset,event) PreProcessLFP_EwellLab(app,LFPax2,chb, thrb,dsb,bandminb,bandmaxb));
            %button to run detection after all settings
            confirm = uibutton(settings, "Text", "Run Detection", "Position", [10 bandlabel.Position(2)-100 180 30], "ButtonPushedFcn", @(confirm,event) SeizureDetectionPushed_EwellLab(app,LFPax2,cbx,chb,thrb,mdb,burstwinb,dsb,bandminb,bandmaxb));
            %button to accept the results of the detection
            accept = uibutton(settings, "Text", "Accept results", "Position", [10 confirm.Position(2)-50 180 30], "ButtonPushedFcn", @(accept,event) AcceptButtonPushed_EwellLab(app, fig,chb));
        
            
            %preprocess LFP & calculate baseline
            PreProcessLFP_EwellLab(app,LFPax2,chb, thrb,dsb,bandminb,bandmaxb);
            %create output directory or enable reading settings from file if it
            %already exist
            parentdir = strcat(app.FileList.ts(1).folder, '\');
            dir = 'Seizure Detection Output';
            if ~exist(strcat(parentdir, dir), 'dir')
                mkdir(parentdir,dir); 

            else
                %create small pop-up window to communicate w/ user - fixed
                %position of the window! 
                app.figmsg = uifigure("Name", "","Position", [450 450  150 75]);
                msgpanel = uipanel(app.figmsg,'Title', 'Load settings from file?', 'Position', [0 0 app.figmsg.Position(3) app.figmsg.Position(4)]);
                %yes and no buttons
                yesb = uibutton(msgpanel, "Text", "Yes", "Position", [20 round(msgpanel.Position(4)/3) 40 20],'ButtonPushedFcn',@(yesb,event) ReadSettings_EwellLab(app,LFPax2,chb,thrb,mdb));
                nob = uibutton(msgpanel, "Text", "No", "Position", [90 round(msgpanel.Position(4)/3) 40 20], 'ButtonPushedFcn',@(nob,event) close(app.figmsg));
            end

            %plot LFP & threshold
            app.line = line(LFPax2, 'XData',[app.filtData.ts(1,1) app.filtData.ts(end,1)], 'YData',[(thrb.Value*app.sd) (thrb.Value*app.sd)],'Color','g','LineWidth',1);
            ChannelChanged_EwellLab(app,LFPax2, chb);
            ThresholdChanged_EwellLab(app,LFPax2,thrb);

        end

        % Button pushed function: PreviousSeizureButton
        function PreviousSeizureButtonPushed(app, event)


            %Function for seizure evaluation. Whenever user pushes the
            %button this function is called
            %set score values to default
            app.SeizureButton.Value = 1;
            app.Stage1Button.Value = 1;
            app.DropDown.Value = 'Left hemisphere'; 
            %disable buttons so user cannot initiate several tasks & clear
            %comment field
            app.CommentTextArea.Value = '';
            app.PreviousSeizureButton.Enable = 'off';
            app.NextSeizureButton.Enable= 'off';
            app.ReplotButton.Enable = 'off';
            app.DropDown.Enable = 'on';
            %current seizure is N = N-1
            app.seizure.n = app.seizure.n-1;
            %plot this seizure
            PlotSeizure_EwellLab(app);
            %Enable next button again if we N does not exceed all seizure
            %number
            if app.seizure.n < length(app.seizure.onset)
                app.NextSeizureButton.Enable = 'on';
            end
            
            %Enable previous button again if N > 1 
            if app.seizure.n > 1
                app.PreviousSeizureButton.Enable = 'on';
            end
            app.ReplotButton.Enable = 'on';
        end

        % Button pushed function: NextSeizureButton
        function NextSeizureButtonPushed(app, event)

            %Function for next seizure button, similar to the function
            %before
            %set score values to default
            app.SeizureButton.Value = 1;
            app.Stage1Button.Value = 1;
            app.DropDown.Value = 'Left hemisphere'; 
            app.DropDown.Enable = 'on';
            %disable buttons & clear comment field
            app.CommentTextArea.Value = '';
            app.PreviousSeizureButton.Enable = 'off';
            app.NextSeizureButton.Enable= 'off';
            app.ReplotButton.Enable = 'off';
            % N = N +1
            app.seizure.n = app.seizure.n+1;
            %send warning message about possible continuation if prev
            %seizure offset occured within 10 s
            if app.seizure.onset(app.seizure.n)- app.seizure.offset(app.seizure.n-1) < 10
                 msgbox('Previous seizure ended within 10 s from this onset. It might be a continuation.', 'Warning');
            end
            %plot the Nth seizure
            PlotSeizure_EwellLab(app);
            %enable buttons on certain criteria 
            if app.seizure.n > 1
                app.PreviousSeizureButton.Enable = 'on';
            end
            
            if app.seizure.n < length(app.seizure.onset)
                app.NextSeizureButton.Enable = 'on';
            end

            app.ReplotButton.Enable = 'on';
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            %Function to replot the seizure: N = N
            %disable buttons
            app.ReplotButton.Enable = 'off';
            app.PreviousSeizureButton.Enable = 'off';
            app.NextSeizureButton.Enable = 'off';

            %clear window

            %plot Nth seizure
            PlotSeizure_EwellLab(app);
            %enable buttons
            app.ReplotButton.Enable = 'on';
            app.PreviousSeizureButton.Enable = 'on';
            app.NextSeizureButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Function to evaluate seizures. It's called when user pushes
            %this button. User selects 1) on which hemisphere did the
            %seizure happened based on the LFP, 2) was it a behavioral seizure or not. After that save button is pushed 

            %assign type of lfp 
            app.seizure.LFPtype(app.seizure.n) = string(app.LFPlooksasButtonGroup.SelectedObject.Text);
            %assign on which hemisphere there is a seizure
            if strcmp(app.seizure.LFPtype(app.seizure.n),'Seizure') == 1
                app.seizure.Hemisphere(app.seizure.n) = string(app.DropDown.Value);
            else
                app.seizure.Hemisphere(app.seizure.n) = "none";
            end
            %assign type of behavior
            app.seizure.Behavior(app.seizure.n) = string(app.BehaviorlooksasButtonGroup.SelectedObject.Text);


            %get comment
            app.seizure.comment(app.seizure.n) = app.CommentTextArea.Value;     
    
            %additional information
            app.seizure.RecordingDate = app.RecordingTime;
            app.seizure.UserID = app.UserID;
            app.seizure.MouseID = char(app.Mouse.ID(app.MouseMember));

            %save the output to matfile. Seizure updated every time it's
            %saved (overwriting is possible)
            ScoredSeizures = app.seizure;
            targetdir = strcat(app.FileList.ts(1).folder, '\Seizure Detection Output\');
            targetfile = strcat(targetdir,'Scoring_', app.Mouse.ID(app.MouseMember), '_', app.RecordingTime, '_', app.UserID,  num2str(app.RecentTime(1)),num2str(app.RecentTime(2)), num2str(app.RecentTime(3)), num2str(app.RecentTime(4)),num2str(app.RecentTime(5)),".mat");
            save(targetfile, 'ScoredSeizures');
            msgbox('Answer was succesfully saved!', 'Message');
        end

        % Callback function
        function WhichhemisphereButtonGroupSelectionChanged(app, event)
            
        end

        % Selection changed function: LFPlooksasButtonGroup
        function LFPlooksasButtonGroupSelectionChanged(app, event)

            if strcmp(app.LFPlooksasButtonGroup.SelectedObject.Text, 'Artifact')  == 1
                app.DropDown.Enable = 'off';
            else
                app.DropDown.Enable = 'on';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SeizureAnalysis10UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {948, 948};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {193, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeizureAnalysis10UIFigure = uifigure('Visible', 'off');
            app.SeizureAnalysis10UIFigure.Name = 'Seizure Analysis 1.0';
            app.SeizureAnalysis10UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SeizureAnalysis10UIFigure.Tag = 'asd';
            app.GridLayout = uigridlayout(app.SeizureAnalysis10UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectChannels = uipanel(app.LeftPanel);
            app.SelectChannels.Title = 'Channels';
            app.TimeWindowsEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeWindowsEditFieldLabel.Text = 'TimeWindow (s)';
            app.TimeWindowsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeWindowsEditField.Limits = [0 Inf];
            app.TimeWindowsEditField.Value = 10;
            app.PlotLFPButton = uibutton(app.LeftPanel, 'push');
            app.PlotLFPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLFPButtonPushed, true);
            app.PlotLFPButton.Enable = 'off';
            app.PlotLFPButton.Text = {'Plot LFP'; ''};
            app.SeizureDetectionButton = uibutton(app.LeftPanel, 'push');
            app.SeizureDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @SeizureDetectionButtonPushed, true);
            app.SeizureDetectionButton.Enable = 'off';
            app.SeizureDetectionButton.Text = {'Seizure Detection'; ''};
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SamplingRatekHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRatekHzEditFieldLabel.Text = 'Sampling Rate (kHz)';
            app.SamplingRatekHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRatekHzEditField.Value = 2;
            app.CommentTextAreaLabel = uilabel(app.LeftPanel);
            app.CommentTextAreaLabel.Text = {'Comment'; ''};
            app.CommentTextArea = uitextarea(app.LeftPanel);
            app.CommentTextArea.Enable = 'off';
            app.DetectionScoringLabel = uilabel(app.LeftPanel);
            app.DetectionScoringLabel.Text = '------  Detection & Scoring ------ ';
            app.PlotsettingsLabel = uilabel(app.LeftPanel);
            app.PlotsettingsLabel.Text = '--------  Plot settings -------- ';
            app.VideolagSpinnerLabel = uilabel(app.LeftPanel);
            app.VideolagSpinnerLabel.Text = {'Video lag'; ''};
            app.VideolagSpinner = uispinner(app.LeftPanel);
            app.VideolagSpinner.Limits = [0 100];
            app.VideolagSpinner.Value = 25;
            app.LFPlooksasButtonGroup = uibuttongroup(app.LeftPanel);
            app.LFPlooksasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LFPlooksasButtonGroupSelectionChanged, true);
            app.LFPlooksasButtonGroup.Title = 'LFP looks as';
            app.SeizureButton = uiradiobutton(app.LFPlooksasButtonGroup);
            app.SeizureButton.Text = 'Seizure';
            app.SeizureButton.Value = true;
            app.ArtifactButton = uiradiobutton(app.LFPlooksasButtonGroup);
            app.ArtifactButton.Text = 'Artifact';
            app.ContinuationButton = uiradiobutton(app.LFPlooksasButtonGroup);
            app.ContinuationButton.Text = 'Continuation';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Left hemisphere', 'Right hemisphere', 'Both hemisphere', 'Can''t decide'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Left hemisphere';
            app.SeizureoccursonLabel = uilabel(app.LeftPanel);
            app.SeizureoccursonLabel.Text = 'Seizure occurs on';
            app.BehaviorlooksasButtonGroup = uibuttongroup(app.LeftPanel);
            app.BehaviorlooksasButtonGroup.Title = 'Behavior looks as';
            app.Stage1Button = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.Stage1Button.Text = 'Stage 1';
            app.Stage1Button.Value = true;
            app.Stage2Button = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.Stage2Button.Text = 'Stage 2';
            app.Stage3Button = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.Stage3Button.Text = 'Stage 3';
            app.Stage4Button = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.Stage4Button.Text = 'Stage 4';
            app.Stage5Button = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.Stage5Button.Text = 'Stage 5';
            app.GroomingButton = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.GroomingButton.Text = 'Grooming';
            app.RestingButton = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.RestingButton.Text = 'Resting';
            app.OtherButton = uiradiobutton(app.BehaviorlooksasButtonGroup);
            app.OtherButton.Text = 'Other';
            app.RightPanel = uipanel(app.GridLayout);
            app.TimeminSliderLabel = uilabel(app.RightPanel);
            app.TimeminSliderLabel.Text = 'Time (min)';
            app.TimeminSlider = uislider(app.RightPanel);
            app.TimeminSlider.ValueChangedFcn = createCallbackFcn(app, @TimeminSliderValueChanged, true);
            app.TimeminSlider.Enable = 'off';
            app.LFPViewerPanel = uipanel(app.RightPanel);
            app.LFPViewerPanel.Title = 'LFP Viewer';
            app.VideoPlaybackPanel = uipanel(app.RightPanel);
            app.VideoPlaybackPanel.Title = 'Video Playback';
            app.PlayVideoButton = uibutton(app.RightPanel, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Enable = 'off';
            app.PlayVideoButton.Text = {'Play Video'; ''};
            app.PreviousSeizureButton = uibutton(app.RightPanel, 'push');
            app.PreviousSeizureButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSeizureButtonPushed, true);
            app.PreviousSeizureButton.Enable = 'off';
            app.PreviousSeizureButton.Text = 'Previous Seizure';
            app.NextSeizureButton = uibutton(app.RightPanel, 'push');
            app.NextSeizureButton.ButtonPushedFcn = createCallbackFcn(app, @NextSeizureButtonPushed, true);
            app.NextSeizureButton.Enable = 'off';
            app.NextSeizureButton.Text = 'Next Seizure';
            app.ChooseWorkingDirectoryButton = uibutton(app.RightPanel, 'push');
            app.ChooseWorkingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWorkingDirectoryButtonPushed, true);
            app.ChooseWorkingDirectoryButton.Text = 'Choose Working Directory!';
            app.path = uieditfield(app.RightPanel, 'text');
            app.MouseIDDropDownLabel = uilabel(app.RightPanel);
            app.MouseIDDropDownLabel.Text = 'Mouse ID';
            app.MouseIDDropDown = uidropdown(app.RightPanel);
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @MouseIDDropDownValueChanged, true);
            app.MouseIDDropDown.Enable = 'off';
            app.DateDropDownLabel = uilabel(app.RightPanel);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RightPanel);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Enable = 'off';
            app.ReadFilesButton = uibutton(app.RightPanel, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Enable = 'off';
            app.ReadFilesButton.Text = 'Read Files';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'___________________________________________________________________________________________________________________________________________________________________________________________________________________'; ''};
            app.ReplotButton = uibutton(app.RightPanel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Enable = 'off';
            app.ReplotButton.Text = 'Replot';
            app.VideoSwitchLabel = uilabel(app.RightPanel);
            app.VideoSwitchLabel.Text = 'Video';
            app.VideoSwitch = uiswitch(app.RightPanel, 'slider');
            % Show the figure after all components are created
            app.SeizureAnalysis10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeizureAnalysisEwellLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeizureAnalysis10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeizureAnalysis10UIFigure)
        end
    end
end